classdef GradeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LetterGradeTextArea       matlab.ui.control.TextArea
        LetterGradeTextAreaLabel  matlab.ui.control.Label
        NumericGradeTextArea      matlab.ui.control.TextArea
        NumericGradeLabel         matlab.ui.control.Label
        PresstoCalculateGradeAverageButton  matlab.ui.control.Button
        GradeCalculatorPanel      matlab.ui.container.Panel
        GradingScalePanel         matlab.ui.container.Panel
        ATextArea_2               matlab.ui.control.TextArea
        ATextArea                 matlab.ui.control.TextArea
        Grade5EditField           matlab.ui.control.EditField
        Grade5EditFieldLabel      matlab.ui.control.Label
        Grade4EditField           matlab.ui.control.EditField
        Grade4EditFieldLabel      matlab.ui.control.Label
        Grade3EditField           matlab.ui.control.EditField
        Grade3EditFieldLabel      matlab.ui.control.Label
        Grade2EditField           matlab.ui.control.EditField
        Grade2EditFieldLabel      matlab.ui.control.Label
        Grade1EditField           matlab.ui.control.EditField
        Grade1EditFieldLabel      matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % create variables for the 5 grades that users will input
        grade1 = "-1"; 
        grade2 = "-1";
        grade3 = "-1";
        grade4 = "-1";
        grade5 = "-1"; 
        gradePlaceholder = "-1";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Grade1EditField
        function Grade1EditFieldValueChanged(app, event)
            app.grade1 = app.Grade1EditField.Value; % set the grade1 variable to the variable entered in the grade 1 field on app  
             % convert the grades to numbers from strings
          % app.grade1 = str2num(app.grade1);
        end

        % Value changed function: Grade2EditField
        function Grade2EditFieldValueChanged(app, event)
            app.grade2 = app.Grade2EditField.Value; % repeat function from grade 1 for all other grades entered
           %app.grade2 = str2num(app.grade2);
        end

        % Value changed function: Grade3EditField
        function Grade3EditFieldValueChanged(app, event)
            app.grade3 = app.Grade3EditField.Value;
           %app.grade3 = str2num(app.grade3);
        end

        % Value changed function: Grade4EditField
        function Grade4EditFieldValueChanged(app, event)
            app.grade4 = app.Grade4EditField.Value;
           %app.grade4 = str2num(app.grade4);
        end

        % Value changed function: Grade5EditField
        function Grade5EditFieldValueChanged(app, event)
            app.grade5 = app.Grade5EditField.Value;
           %app.grade5 = str2num(app.grade5);
            
        end

        % Button pushed function: PresstoCalculateGradeAverageButton
        function PresstoCalculateGradeAverageButtonPushed(app, event)
           % once calculate grade average is pushed, perform necessary
           % calculations and display results properly
           % gradeMatrix = [app.grade1 app.grade2 app.grade3 app.grade4 app.grade5];
%           gradeMatrix(1) = app.grade1;
%           gradeMatrix(2) = app.grade2;
%           gradeMatrix(3) = app.grade3;
%           gradeMatrix(4) = app.grade4;
%           gradeMatrix(5) = app.grade5;
%            for i=1:5
% %gradeMatrix(i) = [app.grade1 app.grade2 app.grade3 app.grade4 app.grade5];
%               if isa(gradeMatrix(i),string) && gradeMatrix(i) == " "
%                     clear gradeMatrix(i)
%               end
%             end
%        %    gradeMatrix = str2double(gradeMatrix);
tempGradeMatrix = [app.grade1 app.grade2 app.grade3 app.grade4 app.grade5 app.gradePlaceholder];
tempGradeMatrix = str2double(tempGradeMatrix);
k=1;
gradeMatrix = 0;
for i=1:5
    if tempGradeMatrix(i) >= 0
        gradeMatrix(k) = tempGradeMatrix(i)
        k=k+1;
    end
end
          % numericGrade = mean (str2double([app.grade1 app.grade2 app.grade3 app.grade4 app.grade5]),"omitmissing");  % average the grades. If there are any grades missing (ie less than 5 grades entered), do not include them in the average
          numericGrade = mean(gradeMatrix(:),"omitmissing");

           % loop to determine letter grade and display it in Letter Grade
           % text area
            if numericGrade >= 90
                app.LetterGradeTextArea.Value = "A";
            elseif numericGrade >= 80
                app.LetterGradeTextArea.Value = "B";
            elseif numericGrade >= 70
                app.LetterGradeTextArea.Value = "C";
            elseif numericGrade >= 60
                app.LetterGradeTextArea.Value = "D";
            else
                app.LetterGradeTextArea.Value = "F";
            end
            numericGrade = num2str(numericGrade); % convert the numeric grade to a string
            app.NumericGradeTextArea.Value = numericGrade; % display numeric grade in numeric grade text area

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GradeCalculatorPanel = uipanel(app.UIFigure);
            app.GradeCalculatorPanel.Title = 'Grade Calculator';
            app.TextArea = uitextarea(app.GradeCalculatorPanel);
            app.TextArea.Value = {'Enter up to 5 grades to calculate the student''s average.'};
            app.Grade1EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade1EditFieldLabel.Text = 'Grade 1:';
            app.Grade1EditField = uieditfield(app.GradeCalculatorPanel, 'text');
            app.Grade1EditField.ValueChangedFcn = createCallbackFcn(app, @Grade1EditFieldValueChanged, true);
            app.Grade2EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade2EditFieldLabel.Text = 'Grade 2:';
            app.Grade2EditField = uieditfield(app.GradeCalculatorPanel, 'text');
            app.Grade2EditField.ValueChangedFcn = createCallbackFcn(app, @Grade2EditFieldValueChanged, true);
            app.Grade3EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade3EditFieldLabel.Text = 'Grade 3:';
            app.Grade3EditField = uieditfield(app.GradeCalculatorPanel, 'text');
            app.Grade3EditField.ValueChangedFcn = createCallbackFcn(app, @Grade3EditFieldValueChanged, true);
            app.Grade4EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade4EditFieldLabel.Text = 'Grade 4:';
            app.Grade4EditField = uieditfield(app.GradeCalculatorPanel, 'text');
            app.Grade4EditField.ValueChangedFcn = createCallbackFcn(app, @Grade4EditFieldValueChanged, true);
            app.Grade5EditFieldLabel = uilabel(app.GradeCalculatorPanel);
            app.Grade5EditFieldLabel.Text = 'Grade 5:';
            app.Grade5EditField = uieditfield(app.GradeCalculatorPanel, 'text');
            app.Grade5EditField.ValueChangedFcn = createCallbackFcn(app, @Grade5EditFieldValueChanged, true);
            app.GradingScalePanel = uipanel(app.GradeCalculatorPanel);
            app.GradingScalePanel.Title = 'Grading Scale';
            app.ATextArea = uitextarea(app.GradingScalePanel);
            app.ATextArea.Value = {'>= 90'; ''; '80-89'; ''; '70-79'; ''; '60-69'; ''; '< 60'};
            app.ATextArea_2 = uitextarea(app.GradingScalePanel);
            app.ATextArea_2.Value = {'A'; ''; 'B'; ''; 'C'; ''; 'D'; ''; 'F'};
            app.PresstoCalculateGradeAverageButton = uibutton(app.UIFigure, 'push');
            app.PresstoCalculateGradeAverageButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoCalculateGradeAverageButtonPushed, true);
            app.PresstoCalculateGradeAverageButton.Text = 'Press to Calculate Grade Average';
            app.NumericGradeLabel = uilabel(app.UIFigure);
            app.NumericGradeLabel.Text = 'Numeric Grade:';
            app.NumericGradeTextArea = uitextarea(app.UIFigure);
            app.LetterGradeTextAreaLabel = uilabel(app.UIFigure);
            app.LetterGradeTextAreaLabel.Text = 'Letter Grade:';
            app.LetterGradeTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GradeCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end